classdef CSE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ImagePreprocessingTab         matlab.ui.container.Tab
        InformationoftwophotoncalciumimagingdatasetsPanel  matlab.ui.container.Panel
        HeightTextArea                matlab.ui.control.TextArea
        HeightTextAreaLabel           matlab.ui.control.Label
        FramesSpinner                 matlab.ui.control.Spinner
        FramesSpinnerLabel            matlab.ui.control.Label
        BitDepthTextArea              matlab.ui.control.TextArea
        BitDepthTextAreaLabel         matlab.ui.control.Label
        WidthTextArea                 matlab.ui.control.TextArea
        WidthTextAreaLabel            matlab.ui.control.Label
        FrameTextArea                 matlab.ui.control.TextArea
        TimeIndexPanel                matlab.ui.container.Panel
        EndFramesSpinner              matlab.ui.control.Spinner
        EndFramesSpinnerLabel         matlab.ui.control.Label
        StartFramesSpinner            matlab.ui.control.Spinner
        StartFramesSpinnerLabel       matlab.ui.control.Label
        DenoiseFramesSpinner          matlab.ui.control.Spinner
        DenoiseFramesSpinnerLabel     matlab.ui.control.Label
        InitialFramesSpinner          matlab.ui.control.Spinner
        InitialFramesSpinnerLabel     matlab.ui.control.Label
        ExportTimeMarkerButton        matlab.ui.control.StateButton
        ViewFramesButton              matlab.ui.control.StateButton
        ParameterSetPanel_2           matlab.ui.container.Panel
        NoiseReductionPanel_2         matlab.ui.container.Panel
        NumberofEpochSpinner          matlab.ui.control.Spinner
        NumberofEpochSpinnerLabel     matlab.ui.control.Label
        SegmentationofNeuronalComponentsPanel_2  matlab.ui.container.Panel
        ScanScaleRatioSpinner         matlab.ui.control.Spinner
        ScanScaleRatioSpinner_2Label  matlab.ui.control.Label
        NumberofCenterPixelsSpinner   matlab.ui.control.Spinner
        NumberofCenterPixelsSpinnerLabel  matlab.ui.control.Label
        StepSizeSpinner               matlab.ui.control.Spinner
        StepSizeSpinnerLabel          matlab.ui.control.Label
        WindowSizeSpinner             matlab.ui.control.Spinner
        WindowSizeSpinnerLabel        matlab.ui.control.Label
        ExtractionofNeuronalSignalPanel_2  matlab.ui.container.Panel
        CalculateDecayIndexButton     matlab.ui.control.Button
        DecayIndexSpinner             matlab.ui.control.Spinner
        DecayIndexSpinnerLabel        matlab.ui.control.Label
        OneFDurationmsSpinner         matlab.ui.control.Spinner
        OneFDurationmsSpinnerLabel    matlab.ui.control.Label
        WorkflowButtonPanel           matlab.ui.container.Panel
        OneClickRunMiceButton         matlab.ui.control.StateButton
        OneClickRunMouseButton        matlab.ui.control.StateButton
        ExtractionofNeuronalSignalPanel  matlab.ui.container.Panel
        EvaluateMaksButton            matlab.ui.control.StateButton
        EventSignalsButton            matlab.ui.control.StateButton
        CalciumSignalsButton          matlab.ui.control.StateButton
        CorrectFluoresceneButton      matlab.ui.control.StateButton
        NeuropilFluorescenceButton    matlab.ui.control.StateButton
        NeuronalFluorescenceButton    matlab.ui.control.StateButton
        MotionCorrectionPanel         matlab.ui.container.Panel
        MotionCorrectionButton        matlab.ui.control.StateButton
        SegmentationofNeuronalComponentsPanel  matlab.ui.container.Panel
        SpatialMasksPanel             matlab.ui.container.Panel
        OverlappingROIsButton         matlab.ui.control.StateButton
        SpatialMaskButton             matlab.ui.control.StateButton
        EmergingOverlapingROIsButton  matlab.ui.control.StateButton
        TemporalMaskPanel             matlab.ui.container.Panel
        CenterPixelButton             matlab.ui.control.StateButton
        TemporalMaskButton            matlab.ui.control.StateButton
        TSNEProjectionButton          matlab.ui.control.StateButton
        PeriodicMasksPanel            matlab.ui.container.Panel
        PotentialLocationButton       matlab.ui.control.StateButton
        PeriodicMasksButton           matlab.ui.control.StateButton
        InitializationButton          matlab.ui.control.StateButton
        NoiseReductionPanel           matlab.ui.container.Panel
        DenoiseModelRunButton         matlab.ui.control.StateButton
        EvaluateDenoiseReconstructionLossButton  matlab.ui.control.StateButton
        EvaluatePersonalModelLossButton  matlab.ui.control.StateButton
        SNRAffterDenoiseButton        matlab.ui.control.StateButton
        DenoiseImagesButton           matlab.ui.control.StateButton
        NormalizeParametersButton     matlab.ui.control.StateButton
        TrainingPersonalMolelButton   matlab.ui.control.StateButton
        ExtractKeyFramesButton        matlab.ui.control.StateButton
        OpenMouseFileButton           matlab.ui.control.StateButton
        DataFilesTextArea             matlab.ui.control.TextArea
    end

    properties (Access = private)
        FilePathTrue
        DataFile
        numFrames
        RawFiles
        RegistrationFiles % Description
        DenoiseFiles % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            base_folder = fileparts(mfilename('fullpath'));
            addpath(base_folder);
            addpath(fullfile(base_folder, "NoRMCorre"))
            addpath(fullfile(base_folder, "Functions"))
            addpath(fullfile(base_folder, "AIModel"))
            addpath(fullfile(base_folder, "network_layers"))
            addpath(genpath(pwd));
            FilePath=which('Copyright');
            app.FilePathTrue=FilePath(1,1:(size(FilePath,2)-12));
        end

        % Value changed function: OpenMouseFileButton
        function OpenMouseFileButtonValueChanged(app, event)
            app.DataFile=uigetdir({},'choose your filepath');
            app.RawFiles=strcat(app.DataFile,'\Raw');
            app.DataFilesTextArea.Value=app.DataFile;
            cd(app.DataFile);
            cd(app.FilePathTrue);
        end

        % Value changed function: OneClickRunMouseButton
        function OneClickRunMouseButtonValueChanged(app, event)
            app.CalculateDecayIndexButtonPushed;
            app.MotionCorrectionButtonValueChanged;
            app.NormalizeParametersButtonValueChanged;
            app.ExtractKeyFramesButtonValueChanged;            
            T=app.OneFDurationmsSpinner.Value;
            [Decision] = NeedTrainAI2(T,app.DataFile,app.FilePathTrue);
            if Decision==0
                app.TrainingPersonalMolelButtonValueChanged;
            end
            app.EvaluatePersonalModelLossButtonValueChanged;
            app.DenoiseImagesButtonValueChanged;
            app.SNRAffterDenoiseButtonValueChanged;
            app.EvaluateDenoiseReconstructionLossButtonValueChanged;
            app.InitializationButtonValueChanged;
            app.PeriodicMasksButtonValueChanged;
            app.PotentialLocationButtonValueChanged;
            app.TSNEProjectionButtonValueChanged;
            app.CenterPixelButtonValueChanged;
            app.TemporalMaskButtonValueChanged;
            app.OverlappingROIsButtonValueChanged;
            app.EmergingOverlapingROIsButtonValueChanged;
            app.SpatialMaskButtonValueChanged;
            app.NeuronalFluorescenceButtonValueChanged;
            app.NeuropilFluorescenceButtonValueChanged;
            app.CorrectFluoresceneButtonValueChanged;
            app.CalciumSignalsButtonValueChanged;
            app.EventSignalsButtonValueChanged;            
            app.EvaluateMaksButtonValueChanged;
            app.ViewFramesButtonValueChanged;
            app.ExportTimeMarkerButtonValueChanged;
        end

        % Value changed function: OneClickRunMiceButton
        function OneClickRunMiceButtonValueChanged(app, event)
            Path='Input your file pathway';
            cd(Path);
            mouse=readmatrix('mouse.xlsx');
            for i=1:size(mouse,1)
                app.DataFile=strcat(Path,'\','Mouse#',num2str(mouse(i,1)));
                app.RawFiles=strcat(app.DataFile,'\Raw');
                app.DataFilesTextArea.Value=app.DataFile;
                app.ScanScaleRatioSpinner.Value=mouse(i,5);
                app.OneFDurationmsSpinner.Value=mouse(i,4);
                app.CalculateDecayIndexButtonPushed;
                app.MotionCorrectionButtonValueChanged;                
                app.NormalizeParametersButtonValueChanged;
                app.ExtractKeyFramesButtonValueChanged;
                M=mouse(i,2:4);
                [Decision] = NeedTrainAI(M,app.DataFile,app.FilePathTrue);
                if Decision==0
                    app.TrainingPersonalMolelButtonValueChanged;
                end
                app.EvaluatePersonalModelLossButtonValueChanged;
                app.DenoiseImagesButtonValueChanged;
                app.SNRAffterDenoiseButtonValueChanged;
                app.EvaluateDenoiseReconstructionLossButtonValueChanged;
                app.InitializationButtonValueChanged;
                app.PeriodicMasksButtonValueChanged;
                app.PotentialLocationButtonValueChanged;
                app.TSNEProjectionButtonValueChanged;
                app.CenterPixelButtonValueChanged;
                app.TemporalMaskButtonValueChanged;
                app.OverlappingROIsButtonValueChanged;
                app.EmergingOverlapingROIsButtonValueChanged;
                app.SpatialMaskButtonValueChanged;
                app.NeuronalFluorescenceButtonValueChanged;
                app.NeuropilFluorescenceButtonValueChanged;
                app.CorrectFluoresceneButtonValueChanged;
                app.CalciumSignalsButtonValueChanged;
                app.EventSignalsButtonValueChanged;                
                app.EvaluateMaksButtonValueChanged;
                app.ViewFramesButtonValueChanged;
                app.ExportTimeMarkerButtonValueChanged;
            end
        end

        % Button pushed function: CalculateDecayIndexButton
        function CalculateDecayIndexButtonPushed(app, event)
            Frame=app.OneFDurationmsSpinner.Value;
            app.DecayIndexSpinner.Value=exp(-Frame/860);
        end

        % Value changed function: MotionCorrectionButton
        function MotionCorrectionButtonValueChanged(app, event)
            gcp;
            app.numFrames=0;
            cd(app.RawFiles);
            namelist=dir(strcat(app.RawFiles,'\*.tif'));
            for k=1:size(namelist,1)
                cd(app.RawFiles);
                B=namelist(k,1);
                filename=B.name;
                app.FrameTextArea.Value=filename;
                imgInfo = imfinfo(filename);
                app.numFrames=app.numFrames+numel(imgInfo);
                tic; data = read_file(filename); toc;
                if k==1
                    Y=data;
                end
                if k>1
                    Y=cat(3,Y,data);
                end
                clear data;
            end
            Y = single(Y);                 % convert to single precision
            T = size(Y,ndims(Y));
            Y = Y - min(Y(:));
            %% set parameters (first try out rigid motion correction)
            options_rigid = NoRMCorreSetParms('d1',size(Y,1),'d2',size(Y,2),'bin_width',200,'max_shift',15,'us_fac',50,'init_batch',200);
            %% perform motion correction
            tic; [M1,shifts1,template1,options_rigid] = normcorre(Y,options_rigid); toc
            [cM1,mM1,vM1] = motion_metrics(M1,10);
            clear M1;
            %% now try non-rigid motion correction (also in parallel)
            options_nonrigid = NoRMCorreSetParms('d1',size(Y,1),'d2',size(Y,2),'grid_size',[32,32],'mot_uf',4,'bin_width',200,'max_shift',15,'max_dev',3,'us_fac',50,'init_batch',200);
            tic; [M2,shifts2,template2,options_nonrigid] = normcorre_batch(Y,options_nonrigid); toc
            %% compute metrics
            nnY = quantile(Y(:),0.005);
            mmY = quantile(Y(:),0.995);
            [cY,mY,vY] = motion_metrics(Y,10);
            [cM2,mM2,vM2] = motion_metrics(M2,10);
            T = length(cY);
            %% plot metrics
            figure(1);
            set(gcf,'unit','centimeters','position',[5 5 25 10]);
            set(gcf,'ToolBar','none','ReSize','off');
            set(gcf,'color','w');
            ax1 = subplot(2,3,1); imagesc(mY,[nnY,mmY]);  axis equal; axis tight; axis off; title('mean raw data','fontsize',14,'fontweight','bold')
            ax2 = subplot(2,3,2); imagesc(mM1,[nnY,mmY]);  axis equal; axis tight; axis off; title('mean rigid corrected','fontsize',14,'fontweight','bold')
            ax3 = subplot(2,3,3); imagesc(mM2,[nnY,mmY]); axis equal; axis tight; axis off; title('mean non-rigid corrected','fontsize',14,'fontweight','bold')
            subplot(2,3,4); plot(1:T,cY,1:T,cM1,1:T,cM2); legend('raw data','rigid','non-rigid'); title('correlation coefficients','fontsize',14,'fontweight','bold')
            subplot(2,3,5); scatter(cY,cM1); hold on; plot([0.9*min(cY),1.05*max(cM1)],[0.9*min(cY),1.05*max(cM1)],'--r'); axis square;
            xlabel('raw data','fontsize',14,'fontweight','bold'); ylabel('rigid corrected','fontsize',14,'fontweight','bold');
            subplot(2,3,6); scatter(cM1,cM2); hold on; plot([0.9*min(cY),1.05*max(cM1)],[0.9*min(cY),1.05*max(cM1)],'--r'); axis square;
            xlabel('rigid corrected','fontsize',14,'fontweight','bold'); ylabel('non-rigid corrected','fontsize',14,'fontweight','bold');
            linkaxes([ax1,ax2,ax3],'xy')
            %% plot shifts
            shifts_r = squeeze(cat(3,shifts1(:).shifts));
            shifts_nr = cat(ndims(shifts2(1).shifts)+1,shifts2(:).shifts);
            shifts_nr = reshape(shifts_nr,[],ndims(Y)-1,T);
            shifts_x = squeeze(shifts_nr(:,1,:))';
            shifts_y = squeeze(shifts_nr(:,2,:))';
            patch_id = 1:size(shifts_x,2);
            str = strtrim(cellstr(int2str(patch_id.')));
            str = cellfun(@(x) ['patch # ',x],str,'un',0);
            figure(2);
            set(gcf,'unit','centimeters','position',[5 5 20 10]);
            set(gcf,'ToolBar','none','ReSize','off');
            set(gcf,'color','w');
            ax1 = subplot(311); plot(1:T,cY,1:T,cM1,1:T,cM2); legend('raw data','rigid','non-rigid'); title('correlation coefficients','fontsize',14,'fontweight','bold')
            set(gca,'Xtick',[])
            ax2 = subplot(312); plot(shifts_x); hold on; plot(shifts_r(:,1),'--k','linewidth',2); title('displacements along x','fontsize',14,'fontweight','bold')
            set(gca,'Xtick',[])
            ax3 = subplot(313); plot(shifts_y); hold on; plot(shifts_r(:,2),'--k','linewidth',2); title('displacements along y','fontsize',14,'fontweight','bold')
            xlabel('timestep','fontsize',14,'fontweight','bold')
            linkaxes([ax1,ax2,ax3],'x')
            cd(app.DataFile);
            mkdir('Registration');
            cd('Registration');
            for j=1:2
                saveas(figure(j),strcat(num2str(j),'.fig'));
                print(figure(j),num2str(j),'-djpeg','-r300');
            end
            close all;clc;
            %% plot a movie with the results
            figure;
            MC_ID=1;F=0;
            outputFileName = strcat(['Regrisration',num2str(MC_ID),'.tif']);
            for t = 1:1:T
                subplot(121);imagesc(Y(:,:,t),[nnY,mmY]); xlabel('raw data','fontsize',14,'fontweight','bold'); axis equal; axis tight;
                title(sprintf('Frame %i out of %i',t,T),'fontweight','bold','fontsize',14); colormap('bone')
                subplot(122);imagesc(M2(:,:,t),[nnY,mmY]); xlabel('non-rigid corrected','fontsize',14,'fontweight','bold'); axis equal; axis tight;
                title(sprintf('Frame %i out of %i',t,T),'fontweight','bold','fontsize',14); colormap('bone')
                set(gca,'XTick',[],'YTick',[]);
                drawnow;
                pause(0.02);
                F=F+1;
                if F>5000
                    MC_ID=MC_ID+1;F=1;
                    outputFileName = strcat(['Regrisration',num2str(MC_ID),'.tif']);
                end
                image(:,:,1)=uint16(M2(:,:,t));
                if F == 1 % 如果是第一个图像，使用'WriteMode'设置为'overwrite'
                    imwrite(image, outputFileName, 'WriteMode', 'overwrite');
                else
                    imwrite(image, outputFileName, 'WriteMode', 'append');
                end
            end
            close all;clc;
            clear Y M2;
            cd(app.FilePathTrue);
        end

        % Value changed function: NormalizeParametersButton
        function NormalizeParametersButtonValueChanged(app, event)
            app.RegistrationFiles=strcat(app.DataFile,'\Registration');
            cd(app.RegistrationFiles);
            namelist=dir(strcat(app.RegistrationFiles,'\*.tif'));
            app.numFrames=0;
            for k=1:size(namelist,1)
                cd(app.RegistrationFiles);
                B=namelist(k,1);
                filename=B.name;
                imgInfo = imfinfo(filename);
                app.numFrames=app.numFrames+numel(imgInfo);
                %Import Data
                data=tiffreadVolume(filename);
                if k==1
                    Data=data;
                end
                if k>1
                    Data=cat(3,Data,data);
                end
                clear data;
            end
            for i=1:size(Data,1)
                for j=1:size(Data,2)
                    data=single(Data(i,j,1:app.numFrames));
                    M_Data(i,j)=mean(data);
                    S_Data(i,j)=std(data);
                    clear data;
                end
            end
            cd(app.DataFile);
            mkdir('Denoise');
            cd('Denoise');
            save('NormalizeParameter.mat',"M_Data","S_Data");
            warning('数据导出完毕，请继续分析！');
            clear Data;
            cd(app.FilePathTrue);
        end

        % Value changed function: ExtractKeyFramesButton
        function ExtractKeyFramesButtonValueChanged(app, event)
            app.RegistrationFiles=strcat(app.DataFile,'\Registration');
            cd(app.RegistrationFiles);
            namelist=dir(strcat(app.RegistrationFiles,'\*.tif'));
            %基本设定
            pre_post_omission=0;
            pre_frame = 30; % Fixed number of frames before the predicted frame
            post_frame = 30; % Fixed number of frames after the predicted frame
            start_frame = 31; % train the network using samples from this frame onwards
            %TIFF Information
            File=strcat(['Sample','.h5']);
            cd(app.DataFile);
            cd('Denoise');
            load('NormalizeParameter.mat');
            M_Data=M_Data(1:512,1:512);
            S_Data=S_Data(1:512,1:512);
            mkdir('Input');
            cd('Input');
            h5create(File,'/stack',[512 512 60 300],'Datatype','single');
            cd(app.DataFile);
            cd('Denoise');
            mkdir('Target');
            cd('Target');
            h5create(File,'/stack',[512 512 1 300],'Datatype','single');
            app.numFrames=0;TrainSize=1;
            h=waitbar(0,'please wait');
            for k=1:size(namelist,1)
                cd(app.RegistrationFiles);
                B=namelist(k,1);
                filename=B.name;
                app.FrameTextArea.Value=filename;
                pause(2);
                imgInfo = imfinfo(filename);
                app.numFrames=app.numFrames+numel(imgInfo);
                %Import Data
                data=single(tiffreadVolume(filename));
                data=data(1:512,1:512,:);
                %Training Set
                end_frame = numel(imgInfo) - post_frame - pre_post_omission;
                for frame_index=start_frame:100:end_frame
                    input_index = (frame_index - pre_frame - pre_post_omission) : (frame_index + post_frame + pre_post_omission );
                    pre_post_omission_index = frame_index-pre_post_omission : frame_index+pre_post_omission;
                    for drop_frame = pre_post_omission_index
                        input_index = input_index(input_index ~= drop_frame); % drop the predicted frame and the frames omitted before and after the predicted frame
                    end
                    Data=cat(3,data(:,:,input_index),data(:,:,frame_index));
                    Data = (Data - M_Data)./S_Data;
                    Data(isnan(Data))=0;
                    input(:,:,:,1) = Data(:,:,1:60);
                    target(:,:,:,1) = Data(:,:,61);
                    clear Data;
                    cd(app.DataFile);
                    cd('Denoise');
                    cd('Input');
                    h5write(File,'/stack',input,[1,1,1,TrainSize],[512,512,60,1]);
                    cd(app.DataFile);
                    cd('Denoise');
                    cd('Target');
                    h5write(File,'/stack',target,[1,1,1,TrainSize],[512,512,1,1]);
                    clear input target;
                    TrainSize=TrainSize+1;
                    str=['运行中……',num2str(TrainSize/300*100),'%'];
                    waitbar(TrainSize/300,h,str);
                    if TrainSize>300
                        break;
                    end
                end
                clear data;
                if TrainSize>300
                    break;
                end
            end
            delete(h);
            app.FramesSpinner.Value=app.numFrames;
            app.HeightTextArea.Value= num2str(imgInfo(1).Height);
            app.WidthTextArea.Value= num2str(imgInfo(1).Width);
            app.BitDepthTextArea.Value = ['uint',num2str(imgInfo(1).BitDepth)];
            cd(app.FilePathTrue);
        end

        % Value changed function: DenoiseModelRunButton
        function DenoiseModelRunButtonValueChanged(app, event)
            app.NormalizeParametersButtonValueChanged;
            app.ExtractKeyFramesButtonValueChanged;
            app.TrainingPersonalMolelButtonValueChanged;
        end

        % Value changed function: TrainingPersonalMolelButton
        function TrainingPersonalMolelButtonValueChanged(app, event)
            cd('AIModel')
            load('2021_07_31_09_49_38_095550_unet_1024_search_mean_squared_error_pre_30_post_30_feat_32_power_1_depth_4_unet_True-0125-0.5732.mat');
            deepinterpnet = layerGraph(deepinterpnet);
            filename = 'Sample.h5';
            cd(app.DataFile);
            cd('Denoise');
            cd('Target');
            info=h5info(filename);
            total_samples=info.Datasets.Dataspace.MaxSize(1,4);
            Target = single(h5read(filename,'/stack',[1,1,1,1],[512,512,1,total_samples]));
            cd(app.DataFile);
            cd('Denoise');
            cd('Input');
            info=h5info(filename);
            total_samples=info.Datasets.Dataspace.MaxSize(1,4);
            Input = single(h5read(filename,'/stack',[1,1,1,1],[512,512,60,total_samples]));
            Epoch=app.NumberofEpochSpinner.Value;
            options = trainingOptions('rmsprop', ...
                'MaxEpochs',Epoch,...
                'MiniBatchSize',15, ...
                'InitialLearnRate',0.0001, ...
                'ValidationFrequency',1,...
                'Verbose',true,...
                'Plots','training-progress');

            cd(app.DataFile);
            cd('Denoise');
            try
                [deepinterpnet,info] = trainNetwork(Input,Target,deepinterpnet,options);
                Files=['Denoise','.mat'];
                save(Files,'deepinterpnet');
                save('AIinfo','info');
            catch
                warning('Problem using trainNetwork.  Returning null.');
                deepinterpnet = [];
            end
            clear Target Input;
            cd(app.FilePathTrue);
        end

        % Value changed function: EvaluatePersonalModelLossButton
        function EvaluatePersonalModelLossButtonValueChanged(app, event)
            app.DenoiseFiles=strcat(app.DataFile,'\Denoise');
            cd(app.DenoiseFiles);
            %Training Loss
            load('AIinfo.mat');
            Loss=info.TrainingLoss;
            Max_Loss=max(Loss(1,:));
            Loss=Loss/Max_Loss;
            mat=reshape(Loss,20,app.NumberofEpochSpinner.Value)';
            Epoch_Loss(:,1)=mean(mat,2);
            Epoch_Loss(:,2)=std(mat,0,2)/sqrt(20);
            figure(1),
            set(gcf,'unit','centimeters','position',[5 5 7.5 5]);
            set(gcf,'ToolBar','none','ReSize','off');
            set(gcf,'color','w');
            tiledlayout(1,1,"TileSpacing","compact",'Padding',"compact");
            nexttile(1);
            x=1:app.NumberofEpochSpinner.Value;
            upper_bound = Epoch_Loss(:,1)' + Epoch_Loss(:,2)';
            lower_bound = Epoch_Loss(:,1)' - Epoch_Loss(:,2)';
            plot(x, Epoch_Loss(:,1)', 'k-', 'LineWidth', 1);hold on;
            fill([x fliplr(x)],[lower_bound fliplr(upper_bound)],[0.5, 0.5, 0.5],'edgealpha', '0', 'facealpha', '.5');
            hold off;
            xlabel('#Epoch');
            ylabel('Loss/%');
            box off;
            set(gca,'FontSize',6,'FontName','Helvetica');
            mkdir('Evaluation');
            cd('Evaluation');
            saveas(figure(1),'1.fig');
            print(figure(1),'1','-djpeg','-r300');
            writematrix(Epoch_Loss,'EpochLoss.csv');
            close all;clc;
            cd(app.FilePathTrue);
        end

        % Value changed function: DenoiseImagesButton
        function DenoiseImagesButtonValueChanged(app, event)
            app.RegistrationFiles=strcat(app.DataFile,'\Registration');
            app.DenoiseFiles=strcat(app.DataFile,'\Denoise');
            %基本设定以及AI模型导入
            cd(app.DenoiseFiles);
            load('Denoise.mat');
            load('NormalizeParameter.mat');
            pre_post_omission=0;
            pre_frame = 30; % Fixed number of frames before the predicted frame
            post_frame = 30; % Fixed number of frames after the predicted frame
            start_frame = 31; % train the network using samples from this frame onwards
            %数据读取及去噪
            cd(app.RegistrationFiles);
            namelist=dir(strcat(app.RegistrationFiles,'\*.tif'));
            FrameLabel=[];app.numFrames=0;
            for k=1:size(namelist,1)
                cd(app.RegistrationFiles);
                B=namelist(k,1);
                filename=B.name;
                imgInfo = imfinfo(filename);
                Label=zeros(numel(imgInfo),2);
                Label(:,1)=k;Label(:,2)=(1:1:numel(imgInfo))';
                FrameLabel=[FrameLabel;Label];
                app.numFrames=app.numFrames+numel(imgInfo);
            end
            app.FramesSpinner.Value=app.numFrames;
            end_frame = app.numFrames - post_frame - pre_post_omission;
            figure(1),
            set(gcf,'unit','centimeters','position',[0 0 15 7.5]);
            set(gcf,'ToolBar','none','ReSize','off');
            set(gcf,'color','w');
            tiledlayout(1,2,"TileSpacing","compact",'Padding',"compact");
            Recon_Loss=[];
            h=waitbar(0,'please wait');
            for target=start_frame:end_frame
                %Drop omitted and target frames
                input_index = (target - pre_frame - pre_post_omission) : (target + post_frame + pre_post_omission );
                pre_post_omission_index = target-pre_post_omission : target+pre_post_omission; % index of frames to be dropped
                for drop_frame = pre_post_omission_index
                    input_index = input_index(input_index ~= drop_frame); % drop the predicted frame and the frames omitted before and after the predicted frame
                end
                cd(app.RegistrationFiles);
                for i=1:size(input_index,2)
                    Stack_index=FrameLabel(input_index(1,i),1);
                    Frame_index=FrameLabel(input_index(1,i),2);
                    filename=namelist(Stack_index,1).name;
                    ophys_infer(:,:,i)=single(imread(filename,Frame_index));
                end
                Stack_index=FrameLabel(target,1);
                Frame_index=FrameLabel(target,2);
                filename=namelist(Stack_index,1).name;
                ophyraw(:,:,1)=single(imread(filename,Frame_index));
                data=cat(3,ophys_infer,ophyraw);
                clear ophys_infer ophyraw;
                data = (data - M_Data)./S_Data;
                data(isnan(data))=0;
                ophys_infer=data(:,:,1:end-1);
                ophyraw=data(:,:,end);
                clear data;
                [Min_Matrix] = SliceMatrix(size(ophyraw,1),size(ophyraw,2));
                ophydenoise=ophyraw.*0;ophydenoiseLable=ophyraw.*0;
                for i=1:size(Min_Matrix,1)
                    Row_start=Min_Matrix(i,1);
                    Row_end=Min_Matrix(i,2);
                    Col_start=Min_Matrix(i,3);
                    Col_end=Min_Matrix(i,4);
                    input=ophys_infer(Row_start:Row_end,Col_start:Col_end,1:60);
                    try
                        ophysinterp = predict(deepinterpnet, input);
                    catch
                        warning('Problem using predict.  Returning null.');
                        ophysinterp = [];
                        break;
                    end
                    ophydenoise(Row_start:Row_end,Col_start:Col_end,i)=ophysinterp;
                    ophydenoiseLable(Row_start:Row_end,Col_start:Col_end,i)=ophysinterp.*0;
                    ophydenoiseLable(Row_start:Row_end,Col_start:Col_end,i)=ophydenoiseLable(Row_start:Row_end,Col_start:Col_end,i)+1;
                end
                if isempty(ophysinterp)
                    warning('Problem using predict.  Returning null.');
                    break;
                end
                ophyDenoise(:,:,1)=sum(ophydenoise,3)./sum(ophydenoiseLable,3);
                [loss] = ReconstructionLoss(ophyDenoise,ophyraw);
                Recon_Loss(target-start_frame+1,1)=target;
                Recon_Loss(target-start_frame+1,2)=loss;
                ophyDenoise  = (ophyDenoise .* S_Data)+M_Data;
                ophyraw  = (ophyraw .* S_Data)+M_Data;
                figure(1),
                nexttile(1);
                imshow(ophyraw,[]);
                nexttile(2);
                imshow(ophyDenoise,[]);
                cd(app.DenoiseFiles);
                ophyDenoise=uint16(ophyDenoise);
                FileNum=ceil(target/5000);
                FileNumPre=ceil((target-1)/5000);
                outputFileName = strcat(['Denoise',num2str(FileNum),'.tif']);
                if FileNum>FileNumPre % 如果是第一个图像，使用'WriteMode'设置为'overwrite'
                    imwrite(ophyDenoise, outputFileName, 'WriteMode', 'overwrite');
                else
                    imwrite(ophyDenoise, outputFileName, 'WriteMode', 'append');
                end
                clear ophyDenoise ophydenoise ophyraw ophydenoiseLable;
                str=['运行中……',num2str(target/end_frame*100),'%'];
                waitbar(target/end_frame,h,str);
            end
            writematrix(Recon_Loss,'Loss.csv');
            delete(h);
            cd(app.FilePathTrue);
        end

        % Value changed function: SNRAffterDenoiseButton
        function SNRAffterDenoiseButtonValueChanged(app, event)
            app.DenoiseFiles=strcat(app.DataFile,'\Denoise');
            cd(app.DenoiseFiles);
            namelist=dir(strcat(app.DenoiseFiles,'\*.tif'));
            app.numFrames=0;
            for k=1:size(namelist,1)
                cd(app.DenoiseFiles);
                B=namelist(k,1);
                filename=B.name;
                imgInfo = imfinfo(filename);
                app.numFrames=app.numFrames+numel(imgInfo);
                %Import Data
                data=tiffreadVolume(filename);
                if k==1
                    Data=data;
                end
                if k>1
                    Data=cat(3,Data,data);
                end
                clear data;
            end
            for i=1:size(Data,1)
                for j=1:size(Data,2)
                    data=single(Data(i,j,1:app.numFrames));
                    M_Data_N(i,j)=mean(data);
                    S_Data_N(i,j)=std(data);
                    clear data;
                end
            end
            save('NormalizeParameterDenoise.mat',"M_Data_N","S_Data_N");
            clear Data;
            cd(app.FilePathTrue);
        end

        % Value changed function: EvaluateDenoiseReconstructionLossButton
        function EvaluateDenoiseReconstructionLossButtonValueChanged(app, event)
            app.DenoiseFiles=strcat(app.DataFile,'\Denoise');
            cd(app.DenoiseFiles);
            Loss=readmatrix('Loss.csv');
            load('NormalizeParameter.mat');
            load('NormalizeParameterDenoise.mat');
            SNR_Denoise=M_Data_N./S_Data_N;SNR_Denoise=SNR_Denoise(:);
            SNR=M_Data./S_Data;SNR=SNR(:);
            figure(1),
            set(gcf,'unit','centimeters','position',[5 5 10 7.5]);
            set(gcf,'ToolBar','none','ReSize','off');
            set(gcf,'color','w');
            tiledlayout(1,1,"TileSpacing","compact",'Padding',"compact");
            nexttile(1);
            histogram(Loss(:,2),'Normalization','percentage','FaceColor',[0.5 0.5 0.5],'EdgeColor','none');
            xlabel('Reconstruction loss');ylabel('Percentage%');
            box off;
            set(gca,'FontSize',6,'FontName','Helvetica');
            SNR_Denoise_Acc=[];k=1;
            for i=0:0.1:60
                SNR_Denoise_Acc(k,1)=i;
                [x,~]=find(SNR_Denoise(:,1)<=i);
                SNR_Denoise_Acc(k,2)=size(x,1)/size(SNR_Denoise,1)*100;
                k=k+1;
            end
            SNR_Acc=[];k=1;
            for i=0:0.1:10
                SNR_Acc(k,1)=i;
                [x,~]=find(SNR(:,1)<=i);
                SNR_Acc(k,2)=size(x,1)/size(SNR,1)*100;
                k=k+1;
            end
            [~,p] = kstest2(SNR_Denoise,SNR);
            figure(2),
            set(gcf,'unit','centimeters','position',[5 5 7.5 10]);
            set(gcf,'ToolBar','none','ReSize','off');
            set(gcf,'color','w');
            tiledlayout(1,1,"TileSpacing","compact",'Padding',"compact");
            nexttile(1);
            scatter(SNR(:,1),SNR_Denoise(:,1),'.','MarkerEdgeColor',[0.5 0.5 0.5]);hold on;
            x=0:0.01:max(SNR(:,1));
            plot(x,x,'Color','#EF002A');hold off;
            xlabel('SNR Raw');ylabel('SNR Denoise');
            set(gca,'FontSize',6,'FontName','Helvetica');
            figure(3),
            set(gcf,'unit','centimeters','position',[0 10 7.5 5]);
            set(gcf,'color','w');
            tiledlayout(1,1,"TileSpacing","loose",'Padding',"compact");
            nexttile(1);
            area(SNR_Acc(:,1), SNR_Acc(:,2), 'FaceColor', [0.5 0.5 0.5],'EdgeColor',[0.5 0.5 0.5],'LineWidth',1,'FaceAlpha',0.5);hold on;
            area(SNR_Denoise_Acc(:,1), SNR_Denoise_Acc(:,2), 'FaceColor',[0 0 0],'EdgeColor',[0 0 0],'LineWidth',1,'FaceAlpha',0.5);
            legend('SNR Raw','SNR Denoise','Location','southeast');
            xlabel('SNR');ylabel('Accumulative%');box off;
            set(gca,'FontSize',6,'FontName','Helvetica');
            cd('Evaluation');
            save('Evaluation.mat','SNR_Denoise_Acc','SNR_Acc','SNR_Denoise','SNR','p');
            for i=1:3
                saveas(figure(i),strcat(num2str(i+1),'.fig'));
                print(figure(i),num2str(i+1),'-djpeg','-r300');
            end
            close all;clc;
            cd(app.FilePathTrue);
        end

        % Value changed function: InitializationButton
        function InitializationButtonValueChanged(app, event)
            app.DenoiseFiles=strcat(app.DataFile,'\Denoise');
            cd(app.DenoiseFiles);
            namelist=dir(strcat(app.DenoiseFiles,'\*.tif'));
            for i=1:size(namelist)
                cd(app.DenoiseFiles);
                B=namelist(i,1);
                filename=B.name;
                data=tiffreadVolume(filename);
                if i==1
                    Data=data;
                end
                if i>1
                    Data=cat(3,Data,data);
                end
                clear data;
            end
            Used_projection=prod(Data,3);
            Sum_Projection=sum(Data,3);
            Max_Projection=max(Data,[],3);
            Mean_Projection=uint16(mean(Data,3));
            Diff_Projection=Max_Projection-Mean_Projection;
            cd(app.DataFile);
            mkdir('Segment');
            cd('Segment');
            % 打开.dat文件以进行写入
            fid = fopen('Denoise.dat', 'w');
            % 将矩阵写入.dat文件
            fwrite(fid, Data, 'uint16');
            % 关闭文件
            fclose(fid);
            clear Data;
            save('Projection.mat','Sum_Projection',"Max_Projection","Mean_Projection","Diff_Projection","Used_projection");
            figure(1),
            set(gcf,'unit','centimeters','position',[10 10 9 7.5]);
            set(gcf,'ToolBar','none','ReSize','off');
            set(gcf,'color','w');
            tiledlayout(1,1,"TileSpacing","compact",'Padding',"compact");
            nexttile(1);
            imagesc(Max_Projection);colormap gray;axis off;
            title('Max Projection');
            set(gca,'FontSize',6,'FontName','Helvetica');
            figure(2),
            set(gcf,'unit','centimeters','position',[10 10 9 7.5]);
            set(gcf,'ToolBar','none','ReSize','off');
            set(gcf,'color','w');
            tiledlayout(1,1,"TileSpacing","compact",'Padding',"compact");
            nexttile(1);
            imagesc(Mean_Projection);colormap gray;axis off;
            title('Mean Projection');
            set(gca,'FontSize',6,'FontName','Helvetica');
            figure(3),
            set(gcf,'unit','centimeters','position',[10 10 9 7.5]);
            set(gcf,'ToolBar','none','ReSize','off');
            set(gcf,'color','w');
            tiledlayout(1,1,"TileSpacing","compact",'Padding',"compact");
            nexttile(1);
            imagesc(Diff_Projection);colormap gray;axis off;
            title('Max-Mean Projection');
            set(gca,'FontSize',6,'FontName','Helvetica');
            mkdir('TemporalMask');
            cd('TemporalMask');
            for i=1:3
                saveas(figure(i),strcat(num2str(i),'.fig'));
                print(figure(i),num2str(i),'-djpeg','-r300');
            end
            close all;clc;
            cd(app.FilePathTrue);
        end

        % Value changed function: PeriodicMasksButton
        function PeriodicMasksButtonValueChanged(app, event)
            WS=app.WindowSizeSpinner.Value;
            SS=app.StepSizeSpinner.Value;
            r=app.ScanScaleRatioSpinner.Value*5;
            Area_Size=pi*r^2*0.5;
            app.DenoiseFiles=strcat(app.DataFile,'\Denoise');
            cd(app.DenoiseFiles);
            namelist=dir(strcat(app.DenoiseFiles,'\*.tif'));
            app.numFrames=0;
            for i=1:size(namelist)
                cd(app.DenoiseFiles);
                B=namelist(i,1);
                filename=B.name;
                imgInfo = imfinfo(filename);
                app.numFrames=app.numFrames+numel(imgInfo);
            end
            Frame_block=(1:SS:app.numFrames)';
            Frame_block=[Frame_block(1:end-1,1) Frame_block(2:end,1)];
            Frame_block(:,2)=Frame_block(:,2)+WS-SS-1;
            [x,~]=find(Frame_block(:,2)<=app.numFrames&Frame_block(:,1)<=app.numFrames);
            Frame_block=Frame_block(x,:);
            cd(app.DataFile);
            cd('Segment');
            load('Projection.mat');
            filename = 'Denoise.dat';  % 数据文件名
            dataSize = [size(Sum_Projection,1),size(Sum_Projection,2),app.numFrames];     % 数据尺寸
            dataType = 'uint16';              % 数据类型，根据实际情况修改
            memMap = memmapfile(filename, 'Format', {dataType, dataSize, 'Denoise'});
            Region_Mask=zeros(size(Sum_Projection,1),size(Sum_Projection,2));
            cd('TemporalMask');
            mkdir('Blocks');
            cd('Blocks');
            MAT_features=[];
            for ii=1:size(Frame_block,1)
                cd(app.DataFile);
                cd('Segment');
                Target_Frame_Start=Frame_block(ii,1);
                Target_Frame_End=Frame_block(ii,2);
                data=memMap.Data.Denoise(1:dataSize(1,1),1:dataSize(1,2),Target_Frame_Start:Target_Frame_End);
                [ROI_Feature,Region_Mask] = TemporalMask(data,Region_Mask,Area_Size);
                MAT_features(:,ii)=ROI_Feature;
                cd('TemporalMask');
                cd('Blocks');
                print(figure(1),num2str(ii),'-djpeg','-r300');
                close all;
            end
            Region_Mask=Region_Mask./size(Frame_block,1);
            figure(1),
            set(gcf,'unit','centimeters','position',[10 10 9 7.5]);
            set(gcf,'ToolBar','none','ReSize','off');
            set(gcf,'color','w');
            tiledlayout(1,1,"TileSpacing","compact",'Padding',"compact");
            nexttile(1);
            imagesc(Region_Mask);colormap gray;axis off;
            title('Temporal Masks');
            set(gca,'FontSize',6,'FontName','Helvetica');
            Region_Mask2=Region_Mask(Region_Mask~=0);
            Region_Duplicate=[];k=1;
            for i=0:0.01:1
                [x,~]=find(Region_Mask2(:,1)<=i);
                Region_Duplicate(k,1)=i;
                Region_Duplicate(k,2)=size(x,1)/size(Region_Mask2,1)*100;
                k=k+1;
            end
            figure(2),
            set(gcf,'unit','centimeters','position',[10 10 7.5 5]);
            set(gcf,'color','w');
            tiledlayout(1,1,"TileSpacing","loose",'Padding',"compact");
            nexttile(1);
            plot(Region_Duplicate(:,1),Region_Duplicate(:,2),'Color',[0 0 0],'LineWidth',1);
            xlabel('Mask Fraction');ylabel('Percentage');
            set(gca,'FontSize',6,'FontName','Helvetica');
            cd(app.DataFile);
            cd('Segment');
            cd('TemporalMask');
            save('Mask.mat',"Frame_block","Region_Mask","Region_Mask2",'Region_Duplicate');
            writematrix(MAT_features,'MAT_features.csv');
            for i=1:2
                saveas(figure(i),strcat(num2str(i+3),'.fig'));
                print(figure(i),num2str(i+3),'-djpeg','-r300');
            end
            close all;clc;
            cd(app.FilePathTrue);
        end

        % Value changed function: PotentialLocationButton
        function PotentialLocationButtonValueChanged(app, event)
            cd(app.DataFile);
            cd('Segment');
            load('Projection.mat');
            cd('TemporalMask');
            load('Mask.mat');
            MAT_features=readmatrix('MAT_features.csv');
            MAT_features=gpuArray(MAT_features);
            [rows, cols] = size(Region_Mask);
            [colIdx, rowIdx] = meshgrid(1:cols, 1:rows);
            rowIdx = rowIdx(:);
            colIdx = colIdx(:);
            values = Region_Mask(:);
            newMatrix = [rowIdx, colIdx, values];
            Treshold=3/size(Frame_block,1);
            [x,~]=find(newMatrix(:,3)>Treshold);
            Potential_Location=newMatrix(x,:);
            MAT_features=MAT_features(x,:);
            [x,~]=find(newMatrix(:,3)<=Treshold);
            Background=newMatrix(x,1:3);
            cd(app.DataFile);
            cd('Segment');
            mkdir('SpatialMask');
            cd('SpatialMask');
            writematrix(Potential_Location,'PotentialLocation.csv');
            writematrix(MAT_features,'MAT_features.csv');
            writematrix(Treshold,'Threshold_Mask.csv');
            writematrix(Background,'Background.csv');
            cd(app.FilePathTrue);
        end

        % Value changed function: TSNEProjectionButton
        function TSNEProjectionButtonValueChanged(app, event)
            cd(app.DataFile);
            cd('Segment');
            cd('SpatialMask');
            Potential_Location=readmatrix('PotentialLocation.csv');
            MAT_features=readmatrix('MAT_features.csv');
            MAT_features=[Potential_Location MAT_features];
            MAT_features = normalize(MAT_features, 'range');
            w=warndlg('Operating PCA...','Warning');
            [~, score, ~, ~, ~] = pca(MAT_features,'NumComponents',100);clear MAT_features;
            delete(w);
            w=warndlg('Operating TSNE...','Warning');
            data_pca = gather(score(:, 1:100));clear score;
            TSNEProjection= tsne(data_pca);
            delete(w);
            figure(1),
            set(gcf,'unit','centimeters','position',[10 10 9 9]);
            set(gcf,'ToolBar','none','ReSize','off');
            set(gcf,'color','w');
            tiledlayout(1,1,"TileSpacing","compact",'Padding',"compact");
            nexttile(1);
            scatter(TSNEProjection(:,1),TSNEProjection(:,2),1,'MarkerFaceColor',[0.5 0.5 0.5],'MarkerEdgeColor','none','MarkerFaceAlpha',0.5);
            box on;xlabel('TSNE1');ylabel('TSNE2');
            set(gca,'FontSize',6,'FontName','Helvetica');
            writematrix(TSNEProjection,'TSNEProjection.csv');
            for i=1:1
                saveas(figure(i),strcat(num2str(i),'.fig'));
                print(figure(i),num2str(i),'-djpeg','-r300');
            end
            close all;
            cd(app.FilePathTrue);
        end

        % Value changed function: CenterPixelButton
        function CenterPixelButtonValueChanged(app, event)
            CP=app.NumberofCenterPixelsSpinner.Value;
            cd(app.DataFile);
            cd('Segment');
            cd('SpatialMask');
            TSNEProjection=readmatrix('TSNEProjection.csv');
            w=warndlg('Operating Kmeans...','Warning');
            Idx=kmeans(TSNEProjection,CP);
            Color_data=hsv(CP);
            figure(1),
            set(gcf,'unit','centimeters','position',[10 10 9 9]);
            set(gcf,'ToolBar','none','ReSize','off');
            set(gcf,'color','w');
            tiledlayout(1,1,"TileSpacing","compact",'Padding',"compact");
            nexttile(1);
            for i=1:CP
                [x,~]=find(Idx(:,1)==i);
                scatter(TSNEProjection(x,1),TSNEProjection(x,2),1,'MarkerFaceColor',Color_data(i,:),'MarkerEdgeColor','none','MarkerFaceAlpha',0.5);hold on;
            end
            box on;xlabel('TSNE1');ylabel('TSNE2');
            set(gca,'FontSize',6,'FontName','Helvetica');
            delete(w);
            Pixel_Center=[];
            h=waitbar(0,'please wait');
            for i=1:CP
                [x,~]=find(Idx(:,1)==i);
                Locations=TSNEProjection(x,1:2);
                Target=mean(Locations,1);
                Distance=pdist2(Locations,Target);
                [x,~]=find(Distance(:,1)==max(Distance(:,1)));
                Pixel_Center(i,1:2)=Locations(x(1,1),1:2);
                str=['分析进度……',num2str(i/CP*100),'%'];
                waitbar(i/CP,h,str);
            end
            delete(h);
            writematrix(Pixel_Center,'Pixel_center.csv');
            writematrix(Color_data,'Color_data1.csv');
            writematrix(Idx,'Idx.csv');
            for i=1:1
                saveas(figure(i),strcat(num2str(i+1),'.fig'));
                print(figure(i),num2str(i+1),'-djpeg','-r300');
            end
            close all;
            cd(app.FilePathTrue);
        end

        % Value changed function: TemporalMaskButton
        function TemporalMaskButtonValueChanged(app, event)
            r=app.ScanScaleRatioSpinner.Value*5;
            Area_Size=pi*r^2*0.5;
            cd(app.DataFile);
            cd('Segment');
            load('Projection.mat');
            cd('SpatialMask');
            Potential_Location=readmatrix('PotentialLocation.csv');
            TSNEProjection=readmatrix('TSNEProjection.csv');
            MAT_features=readmatrix('MAT_features.csv');
            Pixel_Center=readmatrix('Pixel_Center.csv');
            Size_row=size(MAT_features,1);
            Size_col=size(MAT_features,2);
            fid = fopen('Feature.dat', 'w');
            fwrite(fid, MAT_features, 'double');
            fclose(fid);
            clear MAT_features;
            filename = 'Feature.dat';
            dataSize = [Size_row,Size_col];
            dataType = 'double';
            memMap = memmapfile(filename, 'Format', {dataType, dataSize, 'Feature'});
            ROI_Label=[];
            ROI_Num=1;
            figure(1),
            set(gcf,'unit','centimeters','position',[10 10 9 7.5]);
            set(gcf,'ToolBar','none','ReSize','off');
            set(gcf,'color','w');
            tiledlayout(1,1,"TileSpacing","compact",'Padding',"compact");
            nexttile(1);
            imagesc(Diff_Projection);colormap gray;axis off;hold on;
            h=waitbar(0,'please wait');
            for i=1:size(Pixel_Center,1)
                positions = find_row_positions(TSNEProjection(:, 1:2), Pixel_Center(i,:));
                [Descien_use,Pixel_ID] = ROI_Detect3(TSNEProjection, positions, Potential_Location, memMap, Diff_Projection, dataSize,Area_Size);
                if Descien_use==1
                    for jj=1:100
                        c=rand(1,3);
                        if std(c)>0.3
                            break;
                        end
                    end
                    Color_data(ROI_Num,:)=c;
                    ROI_Label(Pixel_ID,ROI_Num)=1;
                    ROI_Num=ROI_Num+1;
                    figure(1),
                    nexttile(1);
                    scatter(Potential_Location(Pixel_ID,2),Potential_Location(Pixel_ID,1),0.1,'MarkerFaceColor',c,'MarkerEdgeColor','none');hold on;
                end
                disp(['已找到ROI数量:',num2str(ROI_Num-1),'识别序列:',num2str(i)]);
                str=['渲染进度……',num2str(i/size(Pixel_Center,1)*100),'%'];
                waitbar(i/size(Pixel_Center,1),h,str);
            end
            delete(h);
            ROI_Num=ROI_Num-1;
            save('ROI.mat',"ROI_Num");
            writematrix(ROI_Label,'ROI_Label.csv');
            writematrix(Color_data,'Color_Data.csv');
            for i=1:1
                saveas(figure(i),strcat(num2str(i+2),'.fig'));
                print(figure(i),num2str(i+2),'-djpeg','-r300');
            end
            close all;
            cd(app.FilePathTrue);
        end

        % Value changed function: OverlappingROIsButton
        function OverlappingROIsButtonValueChanged(app, event)
            cd(app.DataFile);
            cd('Segment');
            load('Projection.mat');
            cd('SpatialMask');
            Potential_Location=readmatrix('PotentialLocation.csv');
            ROI_Label=readmatrix('ROI_Label.csv');
            [Region_Near] = Region_overlap_sense2(ROI_Label,Diff_Projection,Potential_Location);
            [RJSC_result] = Region_overlap_check2(Region_Near,ROI_Label);
            writematrix(Region_Near,'Region_Near.csv');
            writematrix(RJSC_result,'RJSC_result.csv');
            cd(app.FilePathTrue);
        end

        % Value changed function: EmergingOverlapingROIsButton
        function EmergingOverlapingROIsButtonValueChanged(app, event)
            cd(app.DataFile);
            cd('Segment');
            load('Projection.mat');
            cd('SpatialMask');
            ROI_Label=readmatrix('ROI_Label.csv');
            RJSC_result=readmatrix('RJSC_result.csv');
            Potential_Location=readmatrix('PotentialLocation.csv');
            ROI_unique=unique(RJSC_result(:,1));
            Combine_ROI=[];
            for i=1:size(ROI_unique,1)
                [x,~]=find(RJSC_result(:,1)==ROI_unique(i,1));
                target=RJSC_result(x,:);
                target=sortrows(target,-8);
                target(:,9)=ceil(target(:,8)-0.8);
                [x,~]=find(target(:,9)==1);
                if isempty(x)&&target(1,8)>0.3
                    target=target(1,:);
                end
                if ~isempty(x)
                    N=min(x(end,1)+1,size(target,1));
                    target=target(1:N,:);
                end
                Combine_ROI=[Combine_ROI;target];
            end
            [x,~]=find(Combine_ROI(:,8)>0.3);
            Combine_ROI=Combine_ROI(x,1:8);
            matrix=Combine_ROI(:,1:2);
            groups = merge_similar_rows(matrix);
            ROI_USED=matrix(:);ROI_USED=unique(ROI_USED);
            ROI_Label_overlap=[];
            h=waitbar(0,'please wait');
            for i=1:size(groups,1)
                A=groups{i};A=A(:);A=unique(A);
                Location=[];
                for j=1:size(A,1)
                    [x,~]=find(ROI_Label(:,A(j,1))==1);
                    Location=[Location;x];
                end
                Location=unique(Location);
                ROI_Label_overlap(Location,i)=1;
                str=['分析进度1/3……',num2str(i/size(groups,1)*100),'%'];
                waitbar(i/size(groups,1),h,str);
            end
            delete(h);
            ROI_Num2=size(ROI_Label_overlap,2)+1;
            Region=Diff_Projection.*0;
            h=waitbar(0,'please wait');
            for i=1:size(ROI_Label,2)
                isContained = ismember(i, ROI_USED);
                if isContained==0
                    [Location,~]=find(ROI_Label(:,i)==1);
                    for j=1:size(Location,1)
                        Region(Potential_Location(Location(j,1),1),Potential_Location(Location(j,1),2))=1;
                    end
                end
                str=['分析进度2/3……',num2str(i/size(ROI_Label,2)*100),'%'];
                waitbar(i/size(ROI_Label,2),h,str);
            end
            delete(h);
            [LabelImage,N] = bwlabel(Region);
            [rows, cols] = size(LabelImage);
            [colIdx, rowIdx] = meshgrid(1:cols, 1:rows);
            rowIdx = rowIdx(:);
            colIdx = colIdx(:);
            values = LabelImage(:);
            newMatrix = [rowIdx, colIdx, values];
            h=waitbar(0,'please wait');
            for i=1:N
                [x,~]=find(newMatrix(:,3)==i);
                SubBlock_Location_Raw=newMatrix(x,1:2);
                positions = find_row_positions(Potential_Location(:, 1:2), SubBlock_Location_Raw);
                ROI_Label_overlap(positions,ROI_Num2)=1;
                ROI_Num2=ROI_Num2+1;
                str=['分析进度3/3……',num2str(i/N*100),'%'];
                waitbar(i/N,h,str);
            end
            delete(h);
            ROI_Num2=ROI_Num2-1;
            Color_Data=[];
            figure(1),
            set(gcf,'unit','centimeters','position',[10 10 9 7.5]);
            set(gcf,'ToolBar','none','ReSize','off');
            set(gcf,'color','w');
            tiledlayout(1,1,"TileSpacing","compact",'Padding',"compact");
            nexttile(1);
            imagesc(Diff_Projection);colormap gray;axis off;hold on;
            h=waitbar(0,'please wait');
            for i=1:size(ROI_Label_overlap,2)
                for jj=1:100
                    c=rand(1,3);
                    if std(c)>0.3
                        break;
                    end
                end
                Color_Data(i,:)=c;
                [Location,~]=find(ROI_Label_overlap(:,i)==1);
                figure(1),
                nexttile(1);
                scatter(Potential_Location(Location,2),Potential_Location(Location,1),0.1,'MarkerFaceColor',Color_Data(i,:),'MarkerEdgeColor','none');hold on;
                str=['渲染进度……',num2str(i/size(ROI_Label_overlap,2)*100),'%'];
                waitbar(i/size(ROI_Label_overlap,2),h,str);
            end
            delete(h);            
            ROI_Num=size(ROI_Label,2);
            cd(app.DataFile);
            cd('Segment');
            mkdir('SomaMask');
            cd('SomaMask');
            writematrix(Potential_Location,'Potential_Location.csv');
            writematrix(ROI_Label_overlap,'ROI_Label_overlap.csv');
            writematrix(Color_Data,'Color_data1.csv');
            save('ROI.mat',"ROI_Num","ROI_Num2");
            for i=1:1
                saveas(figure(i),strcat(num2str(i),'.fig'));
                print(figure(i),num2str(i),'-djpeg','-r300');
            end
            close all;
            cd(app.FilePathTrue);
        end

        % Value changed function: SpatialMaskButton
        function SpatialMaskButtonValueChanged(app, event)
            cd(app.DataFile);
            cd('Segment');
            load('Projection.mat');
            cd('SpatialMask');
            Background=readmatrix('Background.csv');
            cd(app.DataFile);
            cd('Segment');
            cd('SomaMask');
            Potential_Location=readmatrix('Potential_Location.csv');
            Color_data=readmatrix('Color_data1.csv');
            ROI_Label_overlap=readmatrix('ROI_Label_overlap.csv');
            load('ROI.mat');
            Soma_ID=[];k=1;Color_Data=[];
            Pixel_overlap=sum(ROI_Label_overlap,2);
            for i=1:size(ROI_Label_overlap,2)
                target=ROI_Label_overlap(:,i);
                Target=target.*Pixel_overlap;
                [x,~]=find(Target(:,1)==1);
                if size(x,1)>3
                    Soma_ID(:,k)=Target;
                    Color_Data(k,:)=Color_data(i,:);
                    k=k+1;
                end
            end
            Soma_Num=size(Soma_ID,2);
            figure(1),
            set(gcf,'unit','centimeters','position',[10 10 9 7.5]);
            set(gcf,'ToolBar','none','ReSize','off');
            set(gcf,'color','w');
            tiledlayout(1,1,"TileSpacing","compact",'Padding',"compact");
            nexttile(1);
            imagesc(Diff_Projection);colormap gray;axis off;hold on;
            h=waitbar(0,'please wait');
            for i=1:size(Soma_ID,2)
                [Location,~]=find(Soma_ID(:,i)>=1);
                figure(1),
                nexttile(1);
                scatter(Potential_Location(Location,2),Potential_Location(Location,1),0.1,'MarkerFaceColor',Color_Data(i,:),'MarkerEdgeColor','none');hold on;
                str=['渲染进度……',num2str(i/size(Soma_ID,2)*100),'%'];
                waitbar(i/size(Soma_ID,2),h,str);
            end
            delete(h);
            cd(app.DataFile);
            cd('Segment');
            cd('SomaMask');
            save('NeuronalSoma.mat',"ROI_Num","ROI_Num2","Soma_Num");
            writematrix(Color_Data,'Color_Data.csv');
            writematrix(Soma_ID,'Soma_ID.csv');
            writematrix(Background,'Background.csv');
            for i=1:1
                saveas(figure(i),strcat(num2str(i+1),'.fig'));
                print(figure(i),num2str(i+1),'-djpeg','-r300');
            end
            close all;
            cd(app.FilePathTrue);
        end

        % Value changed function: NeuronalFluorescenceButton
        function NeuronalFluorescenceButtonValueChanged(app, event)
            app.DenoiseFiles=strcat(app.DataFile,'\Denoise');
            cd(app.DenoiseFiles);
            namelist=dir(strcat(app.DenoiseFiles,'\*.tif'));
            app.numFrames=0;
            for i=1:size(namelist)
                cd(app.DenoiseFiles);
                B=namelist(i,1);
                filename=B.name;
                imgInfo = imfinfo(filename);
                app.numFrames=app.numFrames+numel(imgInfo);
            end
            cd(app.DataFile);
            cd('Segment');
            load('Projection.mat');
            cd('SomaMask');
            load('NeuronalSoma.mat');
            Potential_Location=readmatrix('Potential_Location.csv');
            Color_Data=readmatrix('Color_Data.csv');
            Soma_ID=readmatrix('Soma_ID.csv');
            Potential_Location=gpuArray(Potential_Location);
            Color_Data=gpuArray(Color_Data);
            Soma_ID=gpuArray(Soma_ID);
            Diff_Projection=gpuArray(Diff_Projection);
            cd(app.DataFile);
            cd('Segment');
            filename = 'Denoise.dat';  % 数据文件名
            dataSize = [size(Sum_Projection,1),size(Sum_Projection,2),app.numFrames];     % 数据尺寸
            dataType = 'uint16';              % 数据类型，根据实际情况修改
            memMap = memmapfile(filename, 'Format', {dataType, dataSize, 'Denoise'});
            RAWdata=memMap.Data.Denoise(1:size(Sum_Projection,1),1:size(Sum_Projection,2),1:app.numFrames);
            cd(app.DataFile);
            mkdir('Signals');
            cd('Signals');
            for i=1:Soma_Num
                [Target_Signal] = SomaFlr6(Potential_Location,i,app.numFrames,RAWdata,Soma_ID);                
                [x,~]=find(Soma_ID(:,i)>=1);
                Location=Potential_Location(x,1:2);
                SomaIllustration(Diff_Projection,Location,i,Color_Data,app.numFrames,Target_Signal);
                cd(app.DataFile);
                cd('Signals');
                mkdir(num2str(i));
                cd(num2str(i));
                writematrix(Target_Signal,'Flsorescene.csv');
                for j=1:5
                    saveas(figure(j),strcat(num2str(j),'.fig'));
                    print(figure(j),num2str(j),'-djpeg','-r300');
                end
                close all;
                disp(['已完成:',num2str(i),'/',num2str(Soma_Num)]);
            end
            cd(app.FilePathTrue);
        end

        % Value changed function: NeuropilFluorescenceButton
        function NeuropilFluorescenceButtonValueChanged(app, event)
            app.DenoiseFiles=strcat(app.DataFile,'\Denoise');
            cd(app.DenoiseFiles);
            namelist=dir(strcat(app.DenoiseFiles,'\*.tif'));
            app.numFrames=0;
            for i=1:size(namelist)
                cd(app.DenoiseFiles);
                B=namelist(i,1);
                filename=B.name;
                imgInfo = imfinfo(filename);
                app.numFrames=app.numFrames+numel(imgInfo);
            end
            cd(app.DataFile);
            cd('Segment');
            load('Projection.mat');
            cd('SomaMask');
            load('NeuronalSoma.mat');
            Potential_Location=readmatrix('Potential_Location.csv');
            Color_Data=readmatrix('Color_Data.csv');
            Soma_ID=readmatrix('Soma_ID.csv');
            Background=readmatrix('Background.csv');
            Potential_Location=gpuArray(Potential_Location);
            Color_Data=gpuArray(Color_Data);
            Soma_ID=gpuArray(Soma_ID);
            Diff_Projection=gpuArray(Diff_Projection);
            Background=gpuArray(Background);
            cd(app.DataFile);
            cd('Segment');
            filename = 'Denoise.dat';  % 数据文件名
            dataSize = [size(Sum_Projection,1),size(Sum_Projection,2),app.numFrames];     % 数据尺寸
            dataType = 'uint16';              % 数据类型，根据实际情况修改
            memMap = memmapfile(filename, 'Format', {dataType, dataSize, 'Denoise'});
            RAWdata=memMap.Data.Denoise(1:size(Sum_Projection,1),1:size(Sum_Projection,2),1:app.numFrames);
            cd(app.DataFile);
            cd('Signals');
            for i=1:Soma_Num
                [Target_Signal,Location_Baackground] = NeuropilIdentify6(Potential_Location,app.numFrames,RAWdata,i,Soma_ID,Background);
                c=Color_Data(i,:);
                NeuropilIllustration(Diff_Projection,Location_Baackground,c,app.numFrames,Target_Signal);
                cd(app.DataFile);
                cd('Signals');
                cd(num2str(i));
                writematrix(Target_Signal,'Neuropil.csv');
                writematrix(Location_Baackground,'Location_Baackground.csv');
                for j=1:4
                    saveas(figure(j),strcat(num2str(j+5),'.fig'));
                    print(figure(j),num2str(j+5),'-djpeg','-r300');
                end
                close all;
                disp(['已完成:',num2str(i),'/',num2str(Soma_Num)]);
            end
            cd(app.FilePathTrue);
        end

        % Value changed function: CorrectFluoresceneButton
        function CorrectFluoresceneButtonValueChanged(app, event)
            cd(app.DataFile);
            cd('Segment');
            cd('SomaMask');
            load('NeuronalSoma.mat');
            Color_Data=readmatrix('Color_Data.csv');
            Neuron_Label=[];Neuropil_r=[];
            for i=1:Soma_Num
                cd(app.DataFile);
                cd('Signals');
                cd(num2str(i));
                Flsorescene=readmatrix('Flsorescene.csv');
                Neuropil=readmatrix('Neuropil.csv');
                Threshold=mean(Neuropil(:,1))+3*std(Neuropil(:,1));
                is_above_threshold = Flsorescene(:,1) >=Threshold;
                consecutive_three = conv(is_above_threshold, [1, 1, 1], 'valid') == 3;
                Neuron_Label(i,1)=0;Neuropil_r(i,1)=0;
                if any(consecutive_three)
                    [Subtraction_neuropil,r_opt] = NeuropilSubtraction2(Flsorescene,Neuropil);
                    correction=detrend(Subtraction_neuropil(:,1))+median(Subtraction_neuropil(:,1));
                    c=Color_Data(i,:);
                    Neuropil_r(i,1)=r_opt;
                    CorrectionSigalIllustration(Subtraction_neuropil,Neuropil,correction,c,Threshold);
                    writematrix(correction,'RawSignal.csv');
                    writematrix(Threshold,'BackgroundThreshold.csv');
                    writematrix(Subtraction_neuropil,'Subtraction_neuropil.csv');
                    for j=1:4
                        saveas(figure(j),strcat(num2str(j+9),'.fig'));
                        print(figure(j),num2str(j+9),'-djpeg','-r300');
                    end
                    close all;
                    disp(['已完成:',num2str(i),'/',num2str(Soma_Num)]);
                    Neuron_Label(i,1)=1;
                end
            end
            cd(app.DataFile);
            cd('Signals');
            mkdir(num2str(0));
            cd(num2str(0));
            writematrix(Neuron_Label,'Neuron_Label.csv');
            writematrix(Neuropil_r,'Neuropil_r.csv');
            cd(app.FilePathTrue);
        end

        % Value changed function: CalciumSignalsButton
        function CalciumSignalsButtonValueChanged(app, event)
            cd(app.DataFile);
            cd('Segment');
            cd('SomaMask');
            load('NeuronalSoma.mat');
            Color_Data=readmatrix('Color_Data.csv');
            cd(app.DataFile);
            cd('Signals');
            cd(num2str(0));
            Neuron_Label=readmatrix('Neuron_Label.csv');
            for i=1:Soma_Num
                Neuron_Label(i,2)=0;
                if Neuron_Label(i,1)==1
                    cd(app.DataFile);
                    cd('Signals');
                    cd(num2str(i));
                    RawSignal=readmatrix('RawSignal.csv');
                    edges = min(RawSignal(:,1)):50:max(RawSignal(:,1));
                    counts = histcounts(RawSignal, edges);
                    [~, max_index] = max(counts);
                    max_index=max_index(1,1);
                    Baseline=(edges(max_index)+edges(max_index+1))/2;
                    c=Color_Data(i,:);
                    [Calcium_signal,Positive_negtive,Positive_label,Calcium_signal2, Decision] = CalciumSignalIllustration(RawSignal,c,Baseline);
                    writematrix(Baseline,'Baseline.csv');
                    writematrix(Calcium_signal,'Calcium_signal.csv');
                    writematrix(Positive_label,'Positive_label.csv');
                    writematrix(Positive_negtive,'Positive_negtive.csv');
                    if Decision==0
                        for j=1:3
                            saveas(figure(j),strcat(num2str(j+10),'.fig'));
                            print(figure(j),num2str(j+10),'-djpeg','-r300');
                        end
                    end
                    if Decision==1
                        writematrix(Calcium_signal2,'Calcium_signal2.csv');
                        Neuron_Label(i,2)=1;
                        for j=1:4
                            saveas(figure(j),strcat(num2str(j+13),'.fig'));
                            print(figure(j),num2str(j+13),'-djpeg','-r300');
                        end
                    end
                    close all;
                end
                disp(['已完成:',num2str(i),'/',num2str(Soma_Num)]);
            end
            cd(app.DataFile);
            cd('Signals');
            cd(num2str(0));
            writematrix(Neuron_Label,'Neuron_Label.csv');
            cd(app.FilePathTrue);
        end

        % Value changed function: EventSignalsButton
        function EventSignalsButtonValueChanged(app, event)
            cd(app.DataFile);
            cd('Segment');
            cd('SomaMask');
            load('NeuronalSoma.mat');
            Color_Data=readmatrix('Color_Data.csv');
            cd(app.DataFile);
            cd('Signals');
            cd(num2str(0));
            Neuron_Label=readmatrix('Neuron_Label.csv');
            ratio=app.DecayIndexSpinner.Value;
            for i=1:Soma_Num
                if Neuron_Label(i,2)==1
                    cd(app.DataFile);
                    cd('Signals');
                    cd(num2str(i));
                    Calcium_signal=readmatrix('Calcium_signal2.csv');
                    Positive_label=readmatrix('Positive_label.csv');
                    Event_signal=[];Event_signal(1,1)=Calcium_signal(1,1)*(1-ratio);
                    for ii=2:size(Calcium_signal,1)
                        Event_signal(ii,1)=Calcium_signal(ii,1)-Calcium_signal(ii-1,1)*ratio;
                    end
                    Event_signal=Event_signal.*Positive_label;
                    Event_signal(Event_signal<0)=0;
                    c=Color_Data(i,:);
                    figure(1),
                    set(gcf,'unit','centimeters','position',[5 5 20 5]);
                    set(gcf,'ToolBar','none','ReSize','off');
                    set(gcf,'color','w');
                    tiledlayout(1,1,"TileSpacing","compact",'Padding',"compact");
                    nexttile(1);
                    plot(Calcium_signal(:,1),'LineWidth',1,'Color',[0.5 0.5 0.5]);hold on;
                    plot(Event_signal(:,1),'LineWidth',1,'Color',c);
                    xlabel('#Frames');ylabel('ΔF/F');
                    xlim([0,size(Calcium_signal,1)]);box on;
                    set(gca,'FontSize',6,'FontName','Helvetica');
                    figure(2),
                    set(gcf,'unit','centimeters','position',[5 5 20 5]);
                    set(gcf,'ToolBar','none','ReSize','off');
                    set(gcf,'color','w');
                    tiledlayout(1,1,"TileSpacing","compact",'Padding',"compact");
                    nexttile(1);
                    plot(Event_signal(:,1),'LineWidth',1,'Color',c);
                    xlabel('#Frames');ylabel('ΔF/F');
                    xlim([0,size(Calcium_signal,1)]);box on;
                    set(gca,'FontSize',6,'FontName','Helvetica');
                    writematrix(Event_signal,'Event_signal.csv');
                    for j=1:2
                        saveas(figure(j),strcat(num2str(j+17),'.fig'));
                        print(figure(j),num2str(j+17),'-djpeg','-r300');
                    end
                    close all;
                end
                disp(['已完成:',num2str(i),'/',num2str(Soma_Num)]);
            end
            cd(app.FilePathTrue);
        end

        % Value changed function: EvaluateMaksButton
        function EvaluateMaksButtonValueChanged(app, event)
            cd(app.DataFile);
            cd('Segment');
            load('Projection.mat');
            cd('SpatialMask');
            TSNEProjection=readmatrix('TSNEProjection.csv');
            ROI_Label=readmatrix('ROI_Label.csv');
            cd(app.DataFile);
            cd('Segment');
            cd('SomaMask');
            load('NeuronalSoma.mat');
            Color_Data=readmatrix('Color_Data.csv');
            Soma_ID=readmatrix('Soma_ID.csv');
            ROI_Label_overlap=readmatrix('ROI_Label_overlap.csv');            
            cd(app.DataFile);
            cd('Signals');
            cd(num2str(0));
            Neuron_Label=readmatrix('Neuron_Label.csv');
            figure(1),
            set(gcf,'unit','centimeters','position',[10 10 9 9]);
            set(gcf,'ToolBar','none','ReSize','off');
            set(gcf,'color','w');
            tiledlayout(1,1,"TileSpacing","compact",'Padding',"compact");
            nexttile(1);
            scatter(TSNEProjection(:,1),TSNEProjection(:,2),1,'MarkerFaceColor',[0.5 0.5 0.5],'MarkerEdgeColor','none','MarkerFaceAlpha',0.5);hold on;
            for i=1:Soma_Num
                if Neuron_Label(i,2)==1
                [Location,~]=find(Soma_ID(:,i)==1);
                scatter(TSNEProjection(Location,1),TSNEProjection(Location,2),1,'MarkerFaceColor',Color_Data(i,:),'MarkerEdgeColor','none','MarkerFaceAlpha',0.5);hold on;
                end
            end
            box on;xlabel('TSNE1');ylabel('TSNE2');
            set(gca,'FontSize',6,'FontName','Helvetica');
            [x,~]=find(Neuron_Label(:,2)==1);
            Soma_ID_USE=Soma_ID(:,x);
            Pixel_num(1,1)=size(ROI_Label,2);
            Pixel_num(2,1)=size(ROI_Label_overlap,2);
            Pixel_num(3,1)=size(Soma_ID_USE,2);
            Pixel_overlap1=sum(ROI_Label,2);
            Pixel_overlap2=sum(ROI_Label_overlap,2);
            Pixel_overlap3=sum(Soma_ID_USE,2);
            Pixel_overlap1(Pixel_overlap1>1)=1;
            Pixel_overlap2(Pixel_overlap2>1)=1;
            Pixel_overlap3(Pixel_overlap3>1)=1;
            Pixel_num(1,2)=sum(Pixel_overlap1(:));
            Pixel_num(2,2)=sum(Pixel_overlap2(:));
            Pixel_num(3,2)=sum(Pixel_overlap3(:));
            Total_Pixel=size(Diff_Projection,1)*size(Diff_Projection,2);
            Pixel_num(:,2)=Pixel_num(:,2)/Total_Pixel*100;
            str1=strcat("Temporal Mask","");
            str2=strcat("Spatial Mask","");
            str3=strcat("Soma Mask","");
            c=[0.5 0.5 0.5;0.5 0.5 0.5;0.5 0.5 0.5];
            figure(2),
            set(gcf,'unit','centimeters','position',[10 10 3 5.12]);
            set(gcf,'ToolBar','none','ReSize','off');
            set(gcf,'color','w');
            tiledlayout(1,1,"TileSpacing","compact",'Padding',"compact");
            nexttile(1);
            Hdl=BoxChart1(gca,1,Pixel_num(1,1),c(1,:),0.5);
            Hdl=BoxChart1(gca,2,Pixel_num(2,1),c(2,:),0.5);
            Hdl=BoxChart1(gca,3,Pixel_num(3,1),c(2,:),0.5);
            xlim([0.5,3.5]);
            labels = [str1,str2,str3];x=1:3;xticks(x);xticklabels(labels);
            ylabel('Amounts');
            set(gca,'FontSize',6,'FontName','Helvetica');
            figure(3),
            set(gcf,'unit','centimeters','position',[10 10 3 5.12]);
            set(gcf,'ToolBar','none','ReSize','off');
            set(gcf,'color','w');
            tiledlayout(1,1,"TileSpacing","compact",'Padding',"compact");
            nexttile(1);
            Hdl=BoxChart1(gca,1,Pixel_num(1,2),c(1,:),0.5);
            Hdl=BoxChart1(gca,2,Pixel_num(2,2),c(2,:),0.5);
            Hdl=BoxChart1(gca,3,Pixel_num(3,2),c(2,:),0.5);
            xlim([0.5,3.5]);
            labels = [str1,str2,str3];x=1:3;xticks(x);xticklabels(labels);
            ylabel('Pixel/%');
            set(gca,'FontSize',6,'FontName','Helvetica');
            cd(app.DataFile);
            cd('Segment');
            mkdir('Evaluation');
            cd('Evaluation');
            writematrix(Pixel_num,'Pixel_num.csv');
            for i=1:3
                saveas(figure(i),strcat(num2str(i+1),'.fig'));
                print(figure(i),num2str(i+1),'-djpeg','-r300');
            end
            close all;
            cd(app.FilePathTrue);
        end

        % Value changed function: ViewFramesButton
        function ViewFramesButtonValueChanged(app, event)
            app.RawFiles=strcat(app.DataFile,'\Raw');
            cd(app.RawFiles);
            namelist=dir(strcat(app.RawFiles,'\*.tif'));
            app.numFrames=0;
            for i=1:size(namelist)
                cd(app.RawFiles);
                B=namelist(i,1);
                filename=B.name;
                imgInfo = imfinfo(filename);
                app.numFrames=app.numFrames+numel(imgInfo);
            end
            app.InitialFramesSpinner.Value=app.numFrames;
            app.DenoiseFiles=strcat(app.DataFile,'\Denoise');
            cd(app.DenoiseFiles);
            namelist=dir(strcat(app.DenoiseFiles,'\*.tif'));
            app.numFrames=0;
            for i=1:size(namelist)
                cd(app.DenoiseFiles);
                B=namelist(i,1);
                filename=B.name;
                imgInfo = imfinfo(filename);
                app.numFrames=app.numFrames+numel(imgInfo);
            end
            app.DenoiseFramesSpinner.Value=app.numFrames;
            cd(app.FilePathTrue);
        end

        % Value changed function: ExportTimeMarkerButton
        function ExportTimeMarkerButtonValueChanged(app, event)
            Inital_Frames=app.InitialFramesSpinner.Value;
            Denoise_Frames=app.DenoiseFramesSpinner.Value;
            S_Frames=app.StartFramesSpinner.Value;
            E_Frames=S_Frames+Denoise_Frames-1;
            app.EndFramesSpinner.Value=E_Frames;
            t0=app.OneFDurationmsSpinner.Value/1000;
            Time_line=(1:1:Inital_Frames)';
            Time_line=Time_line.*t0;
            TimeLabel=Time_line(S_Frames:E_Frames,1);
            A='Calcium_signal.csv';
            for i=1:100
                cd(app.DataFile);
                cd('Signals');
                cd(num2str(i));
                if exist(A,'file')==2
                    Calcium_signal=readmatrix('Calcium_signal.csv');
                    break;
                end
            end
            if size(Calcium_signal,1)~=size(TimeLabel,1)
                warning('时间对齐错误，请检查');
            end
            if size(Calcium_signal,1)==size(TimeLabel,1)
                cd(app.DataFile);
                cd('Signals');
                cd('0');
                if exist('Time_line.csv','file')
                    delete('Time_line.csv');
                end
                if exist('TimeLabel.csv','file')
                    delete('TimeLabel.csv');
                end
                writematrix(Time_line,'Time_line.csv');
                writematrix(TimeLabel,'TimeLabel.csv');
                disp('successfully!!!');
            end
            cd(app.FilePathTrue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImagePreprocessingTab = uitab(app.TabGroup);
            app.ImagePreprocessingTab.Title = 'Image Preprocessing';
            app.DataFilesTextArea = uitextarea(app.ImagePreprocessingTab);
            app.OpenMouseFileButton = uibutton(app.ImagePreprocessingTab, 'state');
            app.OpenMouseFileButton.ValueChangedFcn = createCallbackFcn(app, @OpenMouseFileButtonValueChanged, true);
            app.OpenMouseFileButton.Text = 'Open Mouse File';
            app.NoiseReductionPanel = uipanel(app.ImagePreprocessingTab);
            app.NoiseReductionPanel.Title = 'Noise Reduction';
            app.ExtractKeyFramesButton = uibutton(app.NoiseReductionPanel, 'state');
            app.ExtractKeyFramesButton.ValueChangedFcn = createCallbackFcn(app, @ExtractKeyFramesButtonValueChanged, true);
            app.ExtractKeyFramesButton.Text = 'Extract Key Frames';
            app.TrainingPersonalMolelButton = uibutton(app.NoiseReductionPanel, 'state');
            app.TrainingPersonalMolelButton.ValueChangedFcn = createCallbackFcn(app, @TrainingPersonalMolelButtonValueChanged, true);
            app.TrainingPersonalMolelButton.Text = 'Training Personal  Molel';
            app.NormalizeParametersButton = uibutton(app.NoiseReductionPanel, 'state');
            app.NormalizeParametersButton.ValueChangedFcn = createCallbackFcn(app, @NormalizeParametersButtonValueChanged, true);
            app.NormalizeParametersButton.Text = 'Normalize  Parameters';
            app.DenoiseImagesButton = uibutton(app.NoiseReductionPanel, 'state');
            app.DenoiseImagesButton.ValueChangedFcn = createCallbackFcn(app, @DenoiseImagesButtonValueChanged, true);
            app.DenoiseImagesButton.Text = 'Denoise Images';
            app.SNRAffterDenoiseButton = uibutton(app.NoiseReductionPanel, 'state');
            app.SNRAffterDenoiseButton.ValueChangedFcn = createCallbackFcn(app, @SNRAffterDenoiseButtonValueChanged, true);
            app.SNRAffterDenoiseButton.Text = 'SNR Affter Denoise';
            app.EvaluatePersonalModelLossButton = uibutton(app.NoiseReductionPanel, 'state');
            app.EvaluatePersonalModelLossButton.ValueChangedFcn = createCallbackFcn(app, @EvaluatePersonalModelLossButtonValueChanged, true);
            app.EvaluatePersonalModelLossButton.Text = 'Evaluate Personal Model (Loss)';
            app.EvaluateDenoiseReconstructionLossButton = uibutton(app.NoiseReductionPanel, 'state');
            app.EvaluateDenoiseReconstructionLossButton.ValueChangedFcn = createCallbackFcn(app, @EvaluateDenoiseReconstructionLossButtonValueChanged, true);
            app.EvaluateDenoiseReconstructionLossButton.Text = 'Evaluate Denoise (Reconstruction Loss)';
            app.DenoiseModelRunButton = uibutton(app.NoiseReductionPanel, 'state');
            app.DenoiseModelRunButton.ValueChangedFcn = createCallbackFcn(app, @DenoiseModelRunButtonValueChanged, true);
            app.DenoiseModelRunButton.Text = 'Denoise Model Run';
            app.SegmentationofNeuronalComponentsPanel = uipanel(app.ImagePreprocessingTab);
            app.SegmentationofNeuronalComponentsPanel.Title = 'Segmentation of Neuronal Components';
            app.PeriodicMasksPanel = uipanel(app.SegmentationofNeuronalComponentsPanel);
            app.PeriodicMasksPanel.Title = 'Periodic Masks';
            app.InitializationButton = uibutton(app.PeriodicMasksPanel, 'state');
            app.InitializationButton.ValueChangedFcn = createCallbackFcn(app, @InitializationButtonValueChanged, true);
            app.InitializationButton.Text = 'Initialization';
            app.PeriodicMasksButton = uibutton(app.PeriodicMasksPanel, 'state');
            app.PeriodicMasksButton.ValueChangedFcn = createCallbackFcn(app, @PeriodicMasksButtonValueChanged, true);
            app.PeriodicMasksButton.Text = 'Periodic Masks';
            app.PotentialLocationButton = uibutton(app.PeriodicMasksPanel, 'state');
            app.PotentialLocationButton.ValueChangedFcn = createCallbackFcn(app, @PotentialLocationButtonValueChanged, true);
            app.PotentialLocationButton.Text = 'Potential Location';
            app.TemporalMaskPanel = uipanel(app.SegmentationofNeuronalComponentsPanel);
            app.TemporalMaskPanel.Title = 'Temporal Mask';
            app.TSNEProjectionButton = uibutton(app.TemporalMaskPanel, 'state');
            app.TSNEProjectionButton.ValueChangedFcn = createCallbackFcn(app, @TSNEProjectionButtonValueChanged, true);
            app.TSNEProjectionButton.Text = 'TSNE Projection';
            app.TemporalMaskButton = uibutton(app.TemporalMaskPanel, 'state');
            app.TemporalMaskButton.ValueChangedFcn = createCallbackFcn(app, @TemporalMaskButtonValueChanged, true);
            app.TemporalMaskButton.Text = 'Temporal Mask';
            app.CenterPixelButton = uibutton(app.TemporalMaskPanel, 'state');
            app.CenterPixelButton.ValueChangedFcn = createCallbackFcn(app, @CenterPixelButtonValueChanged, true);
            app.CenterPixelButton.Text = 'Center Pixel';
            app.SpatialMasksPanel = uipanel(app.SegmentationofNeuronalComponentsPanel);
            app.SpatialMasksPanel.Title = 'Spatial Masks';
            app.EmergingOverlapingROIsButton = uibutton(app.SpatialMasksPanel, 'state');
            app.EmergingOverlapingROIsButton.ValueChangedFcn = createCallbackFcn(app, @EmergingOverlapingROIsButtonValueChanged, true);
            app.EmergingOverlapingROIsButton.Text = 'Emerging Overlaping ROIs';
            app.SpatialMaskButton = uibutton(app.SpatialMasksPanel, 'state');
            app.SpatialMaskButton.ValueChangedFcn = createCallbackFcn(app, @SpatialMaskButtonValueChanged, true);
            app.SpatialMaskButton.Text = 'Spatial Mask';
            app.OverlappingROIsButton = uibutton(app.SpatialMasksPanel, 'state');
            app.OverlappingROIsButton.ValueChangedFcn = createCallbackFcn(app, @OverlappingROIsButtonValueChanged, true);
            app.OverlappingROIsButton.Text = 'Overlapping ROIs';
            app.MotionCorrectionPanel = uipanel(app.ImagePreprocessingTab);
            app.MotionCorrectionPanel.Title = 'Motion Correction';
            app.MotionCorrectionButton = uibutton(app.MotionCorrectionPanel, 'state');
            app.MotionCorrectionButton.ValueChangedFcn = createCallbackFcn(app, @MotionCorrectionButtonValueChanged, true);
            app.MotionCorrectionButton.Text = 'Motion Correction';
            app.ExtractionofNeuronalSignalPanel = uipanel(app.ImagePreprocessingTab);
            app.ExtractionofNeuronalSignalPanel.Title = 'Extraction of Neuronal Signal';
            app.NeuronalFluorescenceButton = uibutton(app.ExtractionofNeuronalSignalPanel, 'state');
            app.NeuronalFluorescenceButton.ValueChangedFcn = createCallbackFcn(app, @NeuronalFluorescenceButtonValueChanged, true);
            app.NeuronalFluorescenceButton.Text = 'Neuronal Fluorescence';
            app.NeuropilFluorescenceButton = uibutton(app.ExtractionofNeuronalSignalPanel, 'state');
            app.NeuropilFluorescenceButton.ValueChangedFcn = createCallbackFcn(app, @NeuropilFluorescenceButtonValueChanged, true);
            app.NeuropilFluorescenceButton.Text = 'Neuropil Fluorescence';
            app.CorrectFluoresceneButton = uibutton(app.ExtractionofNeuronalSignalPanel, 'state');
            app.CorrectFluoresceneButton.ValueChangedFcn = createCallbackFcn(app, @CorrectFluoresceneButtonValueChanged, true);
            app.CorrectFluoresceneButton.Text = 'Correct Fluorescene';
            app.CalciumSignalsButton = uibutton(app.ExtractionofNeuronalSignalPanel, 'state');
            app.CalciumSignalsButton.ValueChangedFcn = createCallbackFcn(app, @CalciumSignalsButtonValueChanged, true);
            app.CalciumSignalsButton.Text = 'Calcium Signals';
            app.EventSignalsButton = uibutton(app.ExtractionofNeuronalSignalPanel, 'state');
            app.EventSignalsButton.ValueChangedFcn = createCallbackFcn(app, @EventSignalsButtonValueChanged, true);
            app.EventSignalsButton.Text = 'Event Signals';
            app.EvaluateMaksButton = uibutton(app.ExtractionofNeuronalSignalPanel, 'state');
            app.EvaluateMaksButton.ValueChangedFcn = createCallbackFcn(app, @EvaluateMaksButtonValueChanged, true);
            app.EvaluateMaksButton.Text = 'Evaluate Maks';
            app.WorkflowButtonPanel = uipanel(app.ImagePreprocessingTab);
            app.WorkflowButtonPanel.Title = 'Workflow Button';
            app.OneClickRunMouseButton = uibutton(app.WorkflowButtonPanel, 'state');
            app.OneClickRunMouseButton.ValueChangedFcn = createCallbackFcn(app, @OneClickRunMouseButtonValueChanged, true);
            app.OneClickRunMouseButton.Text = 'One-Click Run (Mouse)';
            app.OneClickRunMiceButton = uibutton(app.WorkflowButtonPanel, 'state');
            app.OneClickRunMiceButton.ValueChangedFcn = createCallbackFcn(app, @OneClickRunMiceButtonValueChanged, true);
            app.OneClickRunMiceButton.Text = 'One-Click Run (Mice)';
            app.ParameterSetPanel_2 = uipanel(app.ImagePreprocessingTab);
            app.ParameterSetPanel_2.Title = 'Parameter Set';
            app.ExtractionofNeuronalSignalPanel_2 = uipanel(app.ParameterSetPanel_2);
            app.ExtractionofNeuronalSignalPanel_2.Title = 'Extraction of Neuronal Signal';
            app.OneFDurationmsSpinnerLabel = uilabel(app.ExtractionofNeuronalSignalPanel_2);
            app.OneFDurationmsSpinnerLabel.Text = 'One F Duration/ms';
            app.OneFDurationmsSpinner = uispinner(app.ExtractionofNeuronalSignalPanel_2);
            app.OneFDurationmsSpinner.ValueDisplayFormat = '%.4f';
            app.OneFDurationmsSpinner.Value = 100;
            app.DecayIndexSpinnerLabel = uilabel(app.ExtractionofNeuronalSignalPanel_2);
            app.DecayIndexSpinnerLabel.Text = 'Decay Index';
            app.DecayIndexSpinner = uispinner(app.ExtractionofNeuronalSignalPanel_2);
            app.DecayIndexSpinner.ValueDisplayFormat = '%.4f';
            app.CalculateDecayIndexButton = uibutton(app.ExtractionofNeuronalSignalPanel_2, 'push');
            app.CalculateDecayIndexButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateDecayIndexButtonPushed, true);
            app.CalculateDecayIndexButton.Text = 'Calculate Decay Index';
            app.SegmentationofNeuronalComponentsPanel_2 = uipanel(app.ParameterSetPanel_2);
            app.SegmentationofNeuronalComponentsPanel_2.Title = 'Segmentation of Neuronal Components';
            app.WindowSizeSpinnerLabel = uilabel(app.SegmentationofNeuronalComponentsPanel_2);
            app.WindowSizeSpinnerLabel.Text = 'Window Size';
            app.WindowSizeSpinner = uispinner(app.SegmentationofNeuronalComponentsPanel_2);
            app.WindowSizeSpinner.ValueDisplayFormat = '%.0f';
            app.WindowSizeSpinner.Value = 100;
            app.StepSizeSpinnerLabel = uilabel(app.SegmentationofNeuronalComponentsPanel_2);
            app.StepSizeSpinnerLabel.Text = 'Step Size';
            app.StepSizeSpinner = uispinner(app.SegmentationofNeuronalComponentsPanel_2);
            app.StepSizeSpinner.ValueDisplayFormat = '%.0f';
            app.StepSizeSpinner.Value = 20;
            app.NumberofCenterPixelsSpinnerLabel = uilabel(app.SegmentationofNeuronalComponentsPanel_2);
            app.NumberofCenterPixelsSpinnerLabel.Text = 'Number of Center Pixels';
            app.NumberofCenterPixelsSpinner = uispinner(app.SegmentationofNeuronalComponentsPanel_2);
            app.NumberofCenterPixelsSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofCenterPixelsSpinner.Value = 9000;
            app.ScanScaleRatioSpinner_2Label = uilabel(app.SegmentationofNeuronalComponentsPanel_2);
            app.ScanScaleRatioSpinner_2Label.Text = 'Scan Scale Ratio';
            app.ScanScaleRatioSpinner = uispinner(app.SegmentationofNeuronalComponentsPanel_2);
            app.ScanScaleRatioSpinner.ValueDisplayFormat = '%.4f';
            app.ScanScaleRatioSpinner.Value = 1.23;
            app.NoiseReductionPanel_2 = uipanel(app.ParameterSetPanel_2);
            app.NoiseReductionPanel_2.Title = 'Noise Reduction';
            app.NumberofEpochSpinnerLabel = uilabel(app.NoiseReductionPanel_2);
            app.NumberofEpochSpinnerLabel.Text = 'Number of Epoch';
            app.NumberofEpochSpinner = uispinner(app.NoiseReductionPanel_2);
            app.NumberofEpochSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofEpochSpinner.Value = 2500;
            app.TimeIndexPanel = uipanel(app.ImagePreprocessingTab);
            app.TimeIndexPanel.Title = 'Time Index';
            app.ViewFramesButton = uibutton(app.TimeIndexPanel, 'state');
            app.ViewFramesButton.ValueChangedFcn = createCallbackFcn(app, @ViewFramesButtonValueChanged, true);
            app.ViewFramesButton.Text = 'View Frames';
            app.ExportTimeMarkerButton = uibutton(app.TimeIndexPanel, 'state');
            app.ExportTimeMarkerButton.ValueChangedFcn = createCallbackFcn(app, @ExportTimeMarkerButtonValueChanged, true);
            app.ExportTimeMarkerButton.Text = 'Export Time Marker';
            app.InitialFramesSpinnerLabel = uilabel(app.TimeIndexPanel);
            app.InitialFramesSpinnerLabel.Text = 'Initial Frames';
            app.InitialFramesSpinner = uispinner(app.TimeIndexPanel);
            app.InitialFramesSpinner.ValueDisplayFormat = '%.0f';
            app.DenoiseFramesSpinnerLabel = uilabel(app.TimeIndexPanel);
            app.DenoiseFramesSpinnerLabel.Text = 'Denoise Frames';
            app.DenoiseFramesSpinner = uispinner(app.TimeIndexPanel);
            app.DenoiseFramesSpinner.ValueDisplayFormat = '%.0f';
            app.StartFramesSpinnerLabel = uilabel(app.TimeIndexPanel);
            app.StartFramesSpinnerLabel.Text = 'Start Frames';
            app.StartFramesSpinner = uispinner(app.TimeIndexPanel);
            app.StartFramesSpinner.ValueDisplayFormat = '%.0f';
            app.StartFramesSpinner.Value = 31;
            app.EndFramesSpinnerLabel = uilabel(app.TimeIndexPanel);
            app.EndFramesSpinnerLabel.Text = 'End Frames';
            app.EndFramesSpinner = uispinner(app.TimeIndexPanel);
            app.EndFramesSpinner.ValueDisplayFormat = '%.0f';
            app.InformationoftwophotoncalciumimagingdatasetsPanel = uipanel(app.ImagePreprocessingTab);
            app.InformationoftwophotoncalciumimagingdatasetsPanel.Title = 'Information of two-photon calcium imaging datasets';
            app.FrameTextArea = uitextarea(app.InformationoftwophotoncalciumimagingdatasetsPanel);
            app.WidthTextAreaLabel = uilabel(app.InformationoftwophotoncalciumimagingdatasetsPanel);
            app.WidthTextAreaLabel.Text = 'Width';
            app.WidthTextArea = uitextarea(app.InformationoftwophotoncalciumimagingdatasetsPanel);
            app.BitDepthTextAreaLabel = uilabel(app.InformationoftwophotoncalciumimagingdatasetsPanel);
            app.BitDepthTextAreaLabel.Text = 'BitDepth';
            app.BitDepthTextArea = uitextarea(app.InformationoftwophotoncalciumimagingdatasetsPanel);
            app.FramesSpinnerLabel = uilabel(app.InformationoftwophotoncalciumimagingdatasetsPanel);
            app.FramesSpinnerLabel.Text = 'Frames';
            app.FramesSpinner = uispinner(app.InformationoftwophotoncalciumimagingdatasetsPanel);
            app.HeightTextAreaLabel = uilabel(app.InformationoftwophotoncalciumimagingdatasetsPanel);
            app.HeightTextAreaLabel.Text = 'Height';
            app.HeightTextArea = uitextarea(app.InformationoftwophotoncalciumimagingdatasetsPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CSE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end